classdef monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)
        % Object in matlab
        Paths  
        Config  
    end
    
    methods (Access = private)
        
        % Dùng để include toàn bộ path trong dự án
        function bootstrapPaths(app)
        % Xác định vị trí file .mlapp (hoặc .m) của app
        thisFile   = mfilename('fullpath');  % .../mlab/app/CapstoneApp.m(lapp)
        appFolder  = fileparts(thisFile);    % .../mlab/app
        mlabRoot   = fileparts(appFolder);   % .../mlab

        % Thêm mlab root vào path nếu chưa có
        if ~contains(path, mlabRoot)
            addpath(mlabRoot);
        end

        % Thêm scripts (nếu có)
        scriptsFolder = fullfile(mlabRoot, 'scripts');
        if isfolder(scriptsFolder) && ~contains(path, scriptsFolder)
            addpath(genpath(scriptsFolder));
        end

        % Thêm lib (nếu có)
        libFolder = fullfile(mlabRoot, 'lib');
        if isfolder(libFolder) && ~contains(path, libFolder)
            addpath(genpath(libFolder));
        end

        % Đảm bảo chính thư mục app cũng nằm trên path
        if ~contains(path, appFolder)
            addpath(appFolder);
        end
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1) Đảm bảo path cho mlab, scripts, lib, app
            app.bootstrapPaths();
        
            % 2) Khởi tạo environment sau khi path đã OK
            app.Paths  = capstone.env.Paths();   % tạo object
            app.Config = capstone.env.Config;    % class Constant
        
            % (tuỳ chọn) kiểm tra đúng kiểu:
            % assert(isa(app.Paths, 'capstone.env.Paths'));


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end